classdef InterfaceToXLFR5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        DataFolderandFilesPanel         matlab.ui.container.Panel
        DataFolderEditFieldLabel        matlab.ui.control.Label
        DataFolderEditField             matlab.ui.control.EditField
        PolarFile1EditFieldLabel        matlab.ui.control.Label
        PolarFile1EditField             matlab.ui.control.EditField
        PolarFile2EditFieldLabel        matlab.ui.control.Label
        PolarFile2EditField             matlab.ui.control.EditField
        PolarFile3EditFieldLabel        matlab.ui.control.Label
        PolarFile3EditField             matlab.ui.control.EditField
        AirfoilFile1EditFieldLabel      matlab.ui.control.Label
        AirfoilFile1EditField           matlab.ui.control.EditField
        AirfoilFile2EditFieldLabel      matlab.ui.control.Label
        AirfoilFile2EditField           matlab.ui.control.EditField
        AirfoilFile3EditFieldLabel      matlab.ui.control.Label
        AirfoilFile3EditField           matlab.ui.control.EditField
        PolarPlottingPanel              matlab.ui.container.Panel
        ChoosePolarDataButtonGroup      matlab.ui.container.ButtonGroup
        MatchReynoldsandMachinFolderButton  matlab.ui.control.RadioButton
        UseFilesButton                  matlab.ui.control.RadioButton
        ReynoldsEditFieldLabel          matlab.ui.control.Label
        ReynoldsEditField               matlab.ui.control.NumericEditField
        MachEditFieldLabel              matlab.ui.control.Label
        MachEditField                   matlab.ui.control.NumericEditField
        E6Label                         matlab.ui.control.Label
        ChoosePolarPlotButtonGroup      matlab.ui.container.ButtonGroup
        CLversusAlphaButton             matlab.ui.control.RadioButton
        CmversusAlphaButton             matlab.ui.control.RadioButton
        CLversusCDButton                matlab.ui.control.RadioButton
        CmversusCLButton                matlab.ui.control.RadioButton
        PlotButton                      matlab.ui.control.Button
        PolarInterpolationPanel         matlab.ui.container.Panel
        ChooseInterpolationButtonGroup  matlab.ui.container.ButtonGroup
        AlphaButton                     matlab.ui.control.RadioButton
        CLButton                        matlab.ui.control.RadioButton
        ValueEditFieldLabel             matlab.ui.control.Label
        ValueEditField                  matlab.ui.control.NumericEditField
        Reynolds2DEditFieldLabel        matlab.ui.control.Label
        Reynolds2DEditField             matlab.ui.control.NumericEditField
        InterpolateButton               matlab.ui.control.Button
        E6Label_2                       matlab.ui.control.Label
        PlotAirfoilsButton              matlab.ui.control.Button
        SavePolarstoMATButton           matlab.ui.control.Button
        InterpolatedResultsPanel        matlab.ui.container.Panel
        AlphaEditFieldLabel             matlab.ui.control.Label
        AlphaEditField                  matlab.ui.control.NumericEditField
        CLEditFieldLabel                matlab.ui.control.Label
        CLEditField                     matlab.ui.control.NumericEditField
        CDEditFieldLabel                matlab.ui.control.Label
        CDEditField                     matlab.ui.control.NumericEditField
        CmEditFieldLabel                matlab.ui.control.Label
        CmEditField                     matlab.ui.control.NumericEditField
        DesignPointMKSUnitsPanel        matlab.ui.container.Panel
        CLEditField_2Label              matlab.ui.control.Label
        CLEditField_2                   matlab.ui.control.NumericEditField
        E6Label_3                       matlab.ui.control.Label
        AltitudeEditFieldLabel          matlab.ui.control.Label
        AltitudeEditField               matlab.ui.control.NumericEditField
        MassEditFieldLabel              matlab.ui.control.Label
        MassEditField                   matlab.ui.control.NumericEditField
        AirspeedEditFieldLabel          matlab.ui.control.Label
        AirspeedEditField               matlab.ui.control.NumericEditField
        WingAreaEditFieldLabel          matlab.ui.control.Label
        WingAreaEditField               matlab.ui.control.NumericEditField
        WingSpanEditFieldLabel          matlab.ui.control.Label
        WingSpanEditField               matlab.ui.control.NumericEditField
        GravityEditFieldLabel           matlab.ui.control.Label
        GravityEditField                matlab.ui.control.NumericEditField
        MachEditField_2Label            matlab.ui.control.Label
        MachEditField_2                 matlab.ui.control.NumericEditField
        ReynoldsEditField_2Label        matlab.ui.control.Label
        ReynoldsEditField_2             matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        InterfacetoXFLR5Label           matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function fullname = getFullFileName(app,fieldn)
        %GETFULLFILENAME Appends the file from fieldn (1 - 6, 1 - 3 are polars, 4 - 6 are airfoils)
        %to the folder.
        
        folderstr = ".";  % Default
        if ~isempty(app.DataFolderEditField.Value)
            folderstr = app.DataFolderEditField.Value;
        end
        
        if fieldn == 1
            fullname = folderstr + "/" + app.PolarFile1EditField.Value;
        elseif fieldn == 2
            fullname = folderstr + "/" + app.PolarFile2EditField.Value;
        elseif fieldn == 3
            fullname = folderstr + "/" + app.PolarFile3EditField.Value;
        elseif fieldn == 4
            fullname = folderstr + "/" + app.AirfoilFile1EditField.Value;
        elseif fieldn == 5
            fullname = folderstr + "/" + app.AirfoilFile2EditField.Value;
        elseif fieldn == 6
            fullname = folderstr + "/" + app.AirfoilFile3EditField.Value;
        end
            
        end
        
        %---------------------------------------------------------------
        function readXFLR5Polar(app,fieldn,filename,mfilename)
        %READXFLR5POLAR Reads a type 1 polar exported from XFLR5, which
        %should be formatted as below. 
        %   fieldn = 1, 2 or 3 (the polar file number)
        %   filename = IF fieldn ~= [1:3], then use this filename
        %   mfilename = full name of the MAT-file to write to
        % xflr5 v6.12
        % 
        %  Calculated polar for: AS5045 (15%)
        % 
        %  1 1 Reynolds number fixed          Mach number fixed         
        % 
        %  xtrf =   1.000 (top)        1.000 (bottom)
        %  Mach =   0.000     Re =     2.500 e 6     Ncrit =   9.000
        % 
        %   alpha     CL        CD       CDp       Cm    Top Xtr Bot Xtr   Cpmin    Chinge    XCp    
        %  ------- -------- --------- --------- -------- ------- ------- -------- --------- ---------
        %   -4.000  -0.2313   0.00861   0.00307  -0.0438  0.6596  0.0081  -1.7721   0.0000   0.0512
        % (additional lines of data, with alpha increasing)

        if fieldn > 0  &&  fieldn < 4
            fullname = getFullFileName(app,fieldn);
        else
            fullname = filename;
        end
        
        % Open file
        fid = fopen(fullname);

        % Read it
        data = textscan(fid,'%f%f%f%f%f%f%f%f%f%f','HeaderLines',11);

        % Open a matfile to store the data in
        matObj = matfile(mfilename,'Writable',true);

        matObj.alpha = data{1};
        matObj.CL = data{2};
        matObj.CD = data{3};
        matObj.CDp = data{4};
        matObj.Cm = data{5};
        matObj.XCp = data{10};

        fclose(fid);    
        end
        
        %---------------------------------------------------------------
        function [alpha,Cd,Cm] = InterpCl1D(app,Cl,fieldn)
        %INTERPCL1D Interpolates for Cd and Cm given Cl in the polar field
        %file.
        readXFLR5Polar(app,fieldn,"",'tmp.mat');
        mat = load('tmp.mat');
        Cd = -100;
        Cm = -100;
        
        % Manually look for bracketing CL values, then interpolate for alpha
        alpha = -100;  % Initialize to an unreasonable value
        n = length(mat.CL);
        for i = 2:n
            if mat.CL(i) >= Cl  &&  mat.CL(i-1) < Cl
                dCLda = (mat.CL(i) - mat.CL(i-1)) / (mat.alpha(i) - mat.alpha(i-1));
                alpha = mat.alpha(i-1) + (Cl - mat.CL(i-1)) / dCLda;
            end
        end
        % Only update displayed values if a valid alpha was found
        if alpha > -99
            Fcd = griddedInterpolant(mat.alpha,mat.CD);
            Cd = Fcd(alpha);
            Fcm = griddedInterpolant(mat.alpha,mat.Cm);
            Cm = Fcm(alpha);
        end
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotAirfoilsButton
        function PlotAirfoilsButtonPushed(app, event)
            cla(app.UIAxes,'reset');  % Clear anything currently displayed
            app.UIAxes.Title.String = 'Airfoils';
            hold(app.UIAxes,'on');
            grid(app.UIAxes,'off');
            
            % Plot all of the 3 airfoil files which are valid
            nfiles = 0;
            if ~isempty(app.AirfoilFile1EditField.Value)
                fullname1 = getFullFileName(app,4);
                airfoil1 = readmatrix(fullname1,'ExpectedNumVariables',2);
                plot(app.UIAxes,airfoil1(:,1),airfoil1(:,2),'LineWidth',1);
                nfiles = nfiles + 1;
            end
            
            if ~isempty(app.AirfoilFile2EditField.Value)
                fullname2 = getFullFileName(app,5);
                airfoil2 = readmatrix(fullname2,'ExpectedNumVariables',2);
                plot(app.UIAxes,airfoil2(:,1),airfoil2(:,2),'LineWidth',1);
                nfiles = nfiles + 1;
            end
            
            if ~isempty(app.AirfoilFile3EditField.Value)
                fullname3 = getFullFileName(app,6);
                airfoil3 = readmatrix(fullname3,'ExpectedNumVariables',2);
                plot(app.UIAxes,airfoil3(:,1),airfoil3(:,2),'LineWidth',1);
                nfiles = nfiles + 1;
            end
            
            if nfiles > 0
                n = 1;
                ledcell = cell(1,nfiles);
                if ~isempty(app.AirfoilFile1EditField.Value)
                    ledcell(1,n) = { app.AirfoilFile1EditField.Value };
                    n = n + 1;
                end
                if ~isempty(app.AirfoilFile2EditField.Value)
                    ledcell(1,n) = { app.AirfoilFile2EditField.Value };
                    n = n + 1;
                end
                if ~isempty(app.AirfoilFile3EditField.Value)
                    ledcell(1,n) = { app.AirfoilFile3EditField.Value };
                end
                legend(app.UIAxes,ledcell);
            end
            
            app.UIAxes.XLabel.String = 'X';
            app.UIAxes.YLabel.String = 'Y';
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [-0.42 0.42];
            hold(app.UIAxes,'off');
        end

        % Button pushed function: SavePolarstoMATButton
        function SavePolarstoMATButtonPushed(app, event)
            % Write to a MAT-file named like the polar file
            
            % Save all non-empty fields
            nfiles = 0;
            if ~isempty(app.PolarFile1EditField.Value)
                [~,fn,~] = fileparts(app.PolarFile1EditField.Value);
                mfilename1 = fn + ".mat";
                readXFLR5Polar(app,1,"",mfilename1)
                nfiles = nfiles + 1;
            end
            
            if ~isempty(app.PolarFile2EditField.Value)
                [~,fn,~] = fileparts(app.PolarFile2EditField.Value);
                mfilename2 = fn + ".mat";
                readXFLR5Polar(app,2,"",mfilename2)
                nfiles = nfiles + 1;
            end

            if ~isempty(app.PolarFile3EditField.Value)
                [~,fn,~] = fileparts(app.PolarFile3EditField.Value);
                mfilename3 = fn + ".mat";
                readXFLR5Polar(app,3,"",mfilename3)
                nfiles = nfiles + 1;
            end
            
            if nfiles == 0
                msgbox('0 MAT-files were written');
            elseif nfiles == 1
                chmsg = '1 MAT-file was written';
                msgbox(chmsg,'Success');
            else
                chmsg = sprintf('%d MAT-files were written',nfiles);
                msgbox(chmsg,'Success');
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Plot the selected polars
            
            % Set defaults
            nfiles = 0;
            folderstr = ".";
            if ~isempty(app.DataFolderEditField.Value)
                folderstr = app.DataFolderEditField.Value;
            end
            
            % Find all files which match the selected Reynolds and Mach
            if app.MatchReynoldsandMachinFolderButton.Value
                Re = app.ReynoldsEditField.Value;
                M = app.MachEditField.Value;
                restr = sprintf('%s/*Re%.3f_M%.3f_N*.txt',folderstr,Re,M);
                files = dir(restr);  % These are the raw text files from XFLR5
                nfiles = length(files);
                ledcell = cell(1,nfiles);
                for i = 1:nfiles
                    filestr = sprintf('%s/%s',folderstr,files(i).name);
                    readXFLR5Polar(app,0,filestr,'tmp.mat');
                    polars(i) = load('tmp.mat');
                    ledcell(1,i) = { files(i).name };
                end
                
            % Otherwise use the polar files listed
            else
                if ~isempty(app.PolarFile1EditField.Value)
                    nfiles = nfiles + 1;
                    readXFLR5Polar(app,1,"",'tmp.mat');
                    polars(nfiles) = load('tmp.mat');
                end
                if ~isempty(app.PolarFile2EditField.Value)
                    nfiles = nfiles + 1;
                    readXFLR5Polar(app,2,"",'tmp.mat');
                    polars(nfiles) = load('tmp.mat');
                end
                if ~isempty(app.PolarFile3EditField.Value)
                    nfiles = nfiles + 1;
                    readXFLR5Polar(app,3,"",'tmp.mat');
                    polars(nfiles) = load('tmp.mat');
                end
                if nfiles > 0
                    n = 1;
                    ledcell = cell(1,nfiles);
                    if ~isempty(app.PolarFile1EditField.Value)
                        ledcell(1,n) = { app.PolarFile1EditField.Value };
                        n = n + 1;
                    end
                    if ~isempty(app.PolarFile2EditField.Value)
                        ledcell(1,n) = { app.PolarFile2EditField.Value };
                        n = n + 1;
                    end
                    if ~isempty(app.PolarFile3EditField.Value)
                        ledcell(1,n) = { app.PolarFile3EditField.Value };
                    end
                end
            end
            
            % Plot the requested figure
            cla(app.UIAxes,'reset');  % Clear anything currently displayed
            hold(app.UIAxes,'on');
            grid(app.UIAxes,'on');

            % CL versus Alpha
            if app.CLversusAlphaButton.Value
                app.UIAxes.Title.String = 'CL versus Alpha';
                for i = 1:nfiles
                    plot(app.UIAxes,polars(i).alpha,polars(i).CL,'LineWidth',1)
                end
                app.UIAxes.XLabel.String = 'Alpha';
                app.UIAxes.YLabel.String = 'CL';

            % Cm versus Alpha
            elseif app.CmversusAlphaButton.Value
                app.UIAxes.Title.String = 'Cm versus Alpha';
                grid(app.UIAxes,'minor');
                for i = 1:nfiles
                    plot(app.UIAxes,polars(i).alpha,polars(i).Cm,'LineWidth',1)
                end
                app.UIAxes.XLabel.String = 'Alpha';
                app.UIAxes.YLabel.String = 'Cm';

            % CL versus CD
            elseif app.CLversusCDButton.Value
                app.UIAxes.Title.String = 'CL versus CD';
                for i = 1:nfiles
                    plot(app.UIAxes,polars(i).CD,polars(i).CL,'LineWidth',1)
                end
                app.UIAxes.XLabel.String = 'CD';
                app.UIAxes.YLabel.String = 'CL';

            % Cm versus CL
            elseif app.CmversusCLButton.Value
                app.UIAxes.Title.String = 'Cm versus CL';
                for i = 1:nfiles
                    plot(app.UIAxes,polars(i).CL,polars(i).Cm,'LineWidth',1)
                end
                app.UIAxes.XLabel.String = 'CL';
                app.UIAxes.YLabel.String = 'Cm';
            end
            
            % Add the legend
            if nfiles > 0
                legend(app.UIAxes,ledcell);
            end
            hold(app.UIAxes,'off');
        end

        % Button pushed function: InterpolateButton
        function InterpolateButtonPushed(app, event)
            % First figure out how many polar files are present
            yre = zeros(1,3);  % Save the Reynolds number for each field
            nfiles = 0;
            if ~isempty(app.PolarFile1EditField.Value)
                nfiles = nfiles + 1;
                fileno = 1;
                ch = convertStringsToChars(app.PolarFile1EditField.Value);
                j = strfind(app.PolarFile1EditField.Value,'Re');
                yre(1) = sscanf(ch(j+2:j+10),'%f');
            end
            if ~isempty(app.PolarFile2EditField.Value)
                nfiles = nfiles + 1;
                fileno = 2;
                ch = convertStringsToChars(app.PolarFile2EditField.Value);
                j = strfind(app.PolarFile2EditField.Value,'Re');
                yre(2) = sscanf(ch(j+2:j+10),'%f');
            end
            if ~isempty(app.PolarFile3EditField.Value)
                nfiles = nfiles + 1;
                fileno = 3;
                ch = convertStringsToChars(app.PolarFile3EditField.Value);
                j = strfind(app.PolarFile3EditField.Value,'Re');
                yre(3) = sscanf(ch(j+2:j+10),'%f');
            end
            
            if nfiles > 1  &&  app.Reynolds2DEditField.Value <= 0
                msgbox('Reynolds number is invalid. Last file used instead of 2D interpolation.')
            end
        
            % If only 1 file, then use 1D interpolation
            if nfiles == 0
                msgbox('No polar files to interpolate from');
            elseif nfiles == 1
                % Interpolate at the given alpha
                if app.AlphaButton.Value
                    readXFLR5Polar(app,fileno,"",'tmp.mat');
                    mat = load('tmp.mat');
                    alpha = app.ValueEditField.Value;
                    app.AlphaEditField.Value = alpha;
                    Fcl = griddedInterpolant(mat.alpha,mat.CL);
                    app.CLEditField.Value = Fcl(alpha);
                    Fcd = griddedInterpolant(mat.alpha,mat.CD);
                    app.CDEditField.Value = Fcd(alpha);
                    Fcm = griddedInterpolant(mat.alpha,mat.Cm);
                    app.CmEditField.Value = Fcm(alpha);
                    
                % Interpolate at the given CL
                elseif app.CLButton.Value
                    Cl = app.ValueEditField.Value;
                    [alpha,Cd,Cm] = InterpCl1D(app,Cl,fileno);
                    % Only update displayed values if a valid alpha was found
                    if alpha > -99
                        app.CDEditField.Value = Cd;
                        app.CmEditField.Value = Cm;
                        app.CLEditField.Value = Cl;
                        app.AlphaEditField.Value = alpha;
                    end
                end
                
            % If more than 1 file, then use 2D interpolation and Reynolds number
            else
                % Sort the Reynolds numbers
                sorted = zeros(1,3);
                sorted(1) = 1;  sorted(2) = 2;  sorted(3) = 3;  % Initialize
                for i = 1:2
                    if yre(sorted(2)) < yre(sorted(1))
                        temp = sorted(1);
                        sorted(1) = sorted(2);
                        sorted(2) = temp;
                    end
                    if yre(sorted(3)) < yre(sorted(2))
                        temp = sorted(2);
                        sorted(2) = sorted(3);
                        sorted(3) = temp;
                    end
                end
                
                % Read the data
                fileno = 0;
                for i = 1:3
                    if yre(sorted(i)) > 0
                        fileno = fileno + 1;
                        readXFLR5Polar(app,i,"",'tmp.mat');
                        mat(fileno) = load('tmp.mat');
                        matre(fileno) = yre(sorted(i));
                        matnfield(fileno) = sorted(i);
                    end
                end
                nfiles = fileno;  % Update nfiles in case an Re=0 file present
                
                % Interpolate at the given alpha
                if app.AlphaButton.Value
                    alpha = app.ValueEditField.Value;
                    Re = app.Reynolds2DEditField.Value;
                    
                    % ASSUME that the alpha values are the same in every file!
                    xalpha = mat(1).alpha;  % Form the x-grid vector for interpolation
                    nalpha = length(xalpha);

                    % Create data matrices for interpolation
                    CLmatrix = zeros(nalpha,nfiles);
                    CDmatrix = zeros(nalpha,nfiles);
                    Cmmatrix = zeros(nalpha,nfiles);

                    for j = 1:nfiles
                        for i = 1:nalpha
                            CLmatrix(i,j) = mat(j).CL(i);
                            CDmatrix(i,j) = mat(j).CD(i);
                            Cmmatrix(i,j) = mat(j).Cm(i);
                        end
                    end

                    Fcl = griddedInterpolant({xalpha,matre},CLmatrix);
                    app.CLEditField.Value = Fcl(alpha,Re);

                    Fcd = griddedInterpolant({xalpha,matre},CDmatrix);
                    app.CDEditField.Value = Fcd(alpha,Re);

                    Fcm = griddedInterpolant({xalpha,matre},Cmmatrix);
                    app.CmEditField.Value = Fcm(alpha,Re);
                    app.AlphaEditField.Value = alpha;
                    
                % Interpolate at the given CL
                elseif app.CLButton.Value
                    Cl = app.ValueEditField.Value;
                    Re = app.Reynolds2DEditField.Value;
                    
                    % Use 1D function to get the values for Cd and Cm for each Re
                    xcd = zeros(1,nfiles);  % Cd(Cl) value for each Re
                    xcm = zeros(1,nfiles);  % Cm(Cl) value for each Re
                    invalidflag = 0;
                    for i = 1:nfiles
                        [~,xcd(i),xcm(i)] = InterpCl1D(app,Cl,matnfield(i));
                        if xcd(i) < 0  % Unsuccessful
                            invalidflag = 1;
                        end
                    end
                    iflag = logical(invalidflag);

                    if ~iflag
                        Fcd = griddedInterpolant(matre,xcd);
                        app.CDEditField.Value = Fcd(Re);
                        Fcm = griddedInterpolant(matre,xcm);
                        app.CmEditField.Value = Fcm(Re);
                        app.CLEditField.Value = Cl;
                        app.AlphaEditField.Value = 0;  % Since alphas probably differ between files
                    end
                end
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Calculate the design point CL, Mach and Reynolds number at the given conditions
            % This requires an AST license
            [T, a, ~, rho] = atmoscoesa(app.AltitudeEditField.Value);
            V = app.AirspeedEditField.Value;
            Mach = V / a;
            cbar = app.WingAreaEditField.Value / app.WingSpanEditField.Value;
            qinf = 0.5 * rho * V^2;
            CL = app.MassEditField.Value * app.GravityEditField.Value / (qinf * app.WingAreaEditField.Value);
            mu = 1.458E-6 * T^1.5 / (T + 110.4);
            Re = rho * V * cbar / mu;
            
            app.CLEditField_2.Value = CL;
            app.MachEditField_2.Value = Mach;
            app.ReynoldsEditField_2.Value = Re / 1E6;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DataFolderandFilesPanel = uipanel(app.UIFigure);
            app.DataFolderandFilesPanel.Title = 'Data Folder and Files';
            app.DataFolderEditFieldLabel = uilabel(app.DataFolderandFilesPanel);
            app.DataFolderEditFieldLabel.Text = 'Data Folder';
            app.DataFolderEditField = uieditfield(app.DataFolderandFilesPanel, 'text');
            app.PolarFile1EditFieldLabel = uilabel(app.DataFolderandFilesPanel);
            app.PolarFile1EditFieldLabel.Text = 'Polar File #1';
            app.PolarFile1EditField = uieditfield(app.DataFolderandFilesPanel, 'text');
            app.PolarFile2EditFieldLabel = uilabel(app.DataFolderandFilesPanel);
            app.PolarFile2EditFieldLabel.Text = 'Polar File #2';
            app.PolarFile2EditField = uieditfield(app.DataFolderandFilesPanel, 'text');
            app.PolarFile3EditFieldLabel = uilabel(app.DataFolderandFilesPanel);
            app.PolarFile3EditFieldLabel.Text = 'Polar File #3';
            app.PolarFile3EditField = uieditfield(app.DataFolderandFilesPanel, 'text');
            app.AirfoilFile1EditFieldLabel = uilabel(app.DataFolderandFilesPanel);
            app.AirfoilFile1EditFieldLabel.Text = 'Airfoil File #1';
            app.AirfoilFile1EditField = uieditfield(app.DataFolderandFilesPanel, 'text');
            app.AirfoilFile2EditFieldLabel = uilabel(app.DataFolderandFilesPanel);
            app.AirfoilFile2EditFieldLabel.Text = 'Airfoil File #2';
            app.AirfoilFile2EditField = uieditfield(app.DataFolderandFilesPanel, 'text');
            app.AirfoilFile3EditFieldLabel = uilabel(app.DataFolderandFilesPanel);
            app.AirfoilFile3EditFieldLabel.Text = 'Airfoil File #3';
            app.AirfoilFile3EditField = uieditfield(app.DataFolderandFilesPanel, 'text');
            app.PolarPlottingPanel = uipanel(app.UIFigure);
            app.PolarPlottingPanel.Title = 'Polar Plotting';
            app.ChoosePolarDataButtonGroup = uibuttongroup(app.PolarPlottingPanel);
            app.ChoosePolarDataButtonGroup.Title = 'Choose Polar Data';
            app.MatchReynoldsandMachinFolderButton = uiradiobutton(app.ChoosePolarDataButtonGroup);
            app.MatchReynoldsandMachinFolderButton.Text = 'Match Reynolds and Mach in Folder';
            app.MatchReynoldsandMachinFolderButton.Value = true;
            app.UseFilesButton = uiradiobutton(app.ChoosePolarDataButtonGroup);
            app.UseFilesButton.Text = 'Use Files';
            app.ReynoldsEditFieldLabel = uilabel(app.PolarPlottingPanel);
            app.ReynoldsEditFieldLabel.Text = 'Reynolds';
            app.ReynoldsEditField = uieditfield(app.PolarPlottingPanel, 'numeric');
            app.MachEditFieldLabel = uilabel(app.PolarPlottingPanel);
            app.MachEditFieldLabel.Text = 'Mach';
            app.MachEditField = uieditfield(app.PolarPlottingPanel, 'numeric');
            app.E6Label = uilabel(app.PolarPlottingPanel);
            app.E6Label.Text = 'E+6';
            app.ChoosePolarPlotButtonGroup = uibuttongroup(app.PolarPlottingPanel);
            app.ChoosePolarPlotButtonGroup.Title = 'Choose Polar Plot';
            app.CLversusAlphaButton = uiradiobutton(app.ChoosePolarPlotButtonGroup);
            app.CLversusAlphaButton.Text = 'CL versus Alpha';
            app.CLversusAlphaButton.Value = true;
            app.CmversusAlphaButton = uiradiobutton(app.ChoosePolarPlotButtonGroup);
            app.CmversusAlphaButton.Text = 'Cm versus Alpha';
            app.CLversusCDButton = uiradiobutton(app.ChoosePolarPlotButtonGroup);
            app.CLversusCDButton.Text = 'CL versus CD';
            app.CmversusCLButton = uiradiobutton(app.ChoosePolarPlotButtonGroup);
            app.CmversusCLButton.Text = 'Cm versus CL';
            app.PlotButton = uibutton(app.PolarPlottingPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PolarInterpolationPanel = uipanel(app.UIFigure);
            app.PolarInterpolationPanel.Title = 'Polar Interpolation';
            app.ChooseInterpolationButtonGroup = uibuttongroup(app.PolarInterpolationPanel);
            app.ChooseInterpolationButtonGroup.Title = 'Choose Interpolation';
            app.AlphaButton = uiradiobutton(app.ChooseInterpolationButtonGroup);
            app.AlphaButton.Text = 'Alpha';
            app.AlphaButton.Value = true;
            app.CLButton = uiradiobutton(app.ChooseInterpolationButtonGroup);
            app.CLButton.Text = 'CL';
            app.ValueEditFieldLabel = uilabel(app.PolarInterpolationPanel);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueEditField = uieditfield(app.PolarInterpolationPanel, 'numeric');
            app.Reynolds2DEditFieldLabel = uilabel(app.PolarInterpolationPanel);
            app.Reynolds2DEditFieldLabel.Text = 'Reynolds (2D)';
            app.Reynolds2DEditField = uieditfield(app.PolarInterpolationPanel, 'numeric');
            app.InterpolateButton = uibutton(app.PolarInterpolationPanel, 'push');
            app.InterpolateButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolateButtonPushed, true);
            app.InterpolateButton.Text = 'Interpolate';
            app.E6Label_2 = uilabel(app.PolarInterpolationPanel);
            app.E6Label_2.Text = 'E+6';
            app.PlotAirfoilsButton = uibutton(app.UIFigure, 'push');
            app.PlotAirfoilsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAirfoilsButtonPushed, true);
            app.PlotAirfoilsButton.Text = 'Plot Airfoils';
            app.SavePolarstoMATButton = uibutton(app.UIFigure, 'push');
            app.SavePolarstoMATButton.ButtonPushedFcn = createCallbackFcn(app, @SavePolarstoMATButtonPushed, true);
            app.SavePolarstoMATButton.Text = 'Save Polars to MAT';
            app.InterpolatedResultsPanel = uipanel(app.UIFigure);
            app.InterpolatedResultsPanel.Title = 'Interpolated Results';
            app.AlphaEditFieldLabel = uilabel(app.InterpolatedResultsPanel);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.InterpolatedResultsPanel, 'numeric');
            app.AlphaEditField.Editable = 'off';
            app.CLEditFieldLabel = uilabel(app.InterpolatedResultsPanel);
            app.CLEditFieldLabel.Text = 'CL';
            app.CLEditField = uieditfield(app.InterpolatedResultsPanel, 'numeric');
            app.CLEditField.Editable = 'off';
            app.CDEditFieldLabel = uilabel(app.InterpolatedResultsPanel);
            app.CDEditFieldLabel.Text = 'CD';
            app.CDEditField = uieditfield(app.InterpolatedResultsPanel, 'numeric');
            app.CDEditField.Editable = 'off';
            app.CmEditFieldLabel = uilabel(app.InterpolatedResultsPanel);
            app.CmEditFieldLabel.Text = 'Cm';
            app.CmEditField = uieditfield(app.InterpolatedResultsPanel, 'numeric');
            app.CmEditField.Editable = 'off';
            app.DesignPointMKSUnitsPanel = uipanel(app.UIFigure);
            app.DesignPointMKSUnitsPanel.Title = 'Design Point (MKS Units)';
            app.CLEditField_2Label = uilabel(app.DesignPointMKSUnitsPanel);
            app.CLEditField_2Label.Text = 'CL';
            app.CLEditField_2 = uieditfield(app.DesignPointMKSUnitsPanel, 'numeric');
            app.CLEditField_2.Editable = 'off';
            app.E6Label_3 = uilabel(app.DesignPointMKSUnitsPanel);
            app.E6Label_3.Text = 'E+6';
            app.AltitudeEditFieldLabel = uilabel(app.DesignPointMKSUnitsPanel);
            app.AltitudeEditFieldLabel.Text = 'Altitude';
            app.AltitudeEditField = uieditfield(app.DesignPointMKSUnitsPanel, 'numeric');
            app.MassEditFieldLabel = uilabel(app.DesignPointMKSUnitsPanel);
            app.MassEditFieldLabel.Text = 'Mass';
            app.MassEditField = uieditfield(app.DesignPointMKSUnitsPanel, 'numeric');
            app.AirspeedEditFieldLabel = uilabel(app.DesignPointMKSUnitsPanel);
            app.AirspeedEditFieldLabel.Text = 'Airspeed';
            app.AirspeedEditField = uieditfield(app.DesignPointMKSUnitsPanel, 'numeric');
            app.WingAreaEditFieldLabel = uilabel(app.DesignPointMKSUnitsPanel);
            app.WingAreaEditFieldLabel.Text = 'Wing Area';
            app.WingAreaEditField = uieditfield(app.DesignPointMKSUnitsPanel, 'numeric');
            app.WingSpanEditFieldLabel = uilabel(app.DesignPointMKSUnitsPanel);
            app.WingSpanEditFieldLabel.Text = 'Wing Span';
            app.WingSpanEditField = uieditfield(app.DesignPointMKSUnitsPanel, 'numeric');
            app.GravityEditFieldLabel = uilabel(app.DesignPointMKSUnitsPanel);
            app.GravityEditFieldLabel.Text = 'Gravity';
            app.GravityEditField = uieditfield(app.DesignPointMKSUnitsPanel, 'numeric');
            app.GravityEditField.Value = 9.81;
            app.MachEditField_2Label = uilabel(app.DesignPointMKSUnitsPanel);
            app.MachEditField_2Label.Text = 'Mach';
            app.MachEditField_2 = uieditfield(app.DesignPointMKSUnitsPanel, 'numeric');
            app.MachEditField_2.Editable = 'off';
            app.ReynoldsEditField_2Label = uilabel(app.DesignPointMKSUnitsPanel);
            app.ReynoldsEditField_2Label.Text = 'Reynolds';
            app.ReynoldsEditField_2 = uieditfield(app.DesignPointMKSUnitsPanel, 'numeric');
            app.ReynoldsEditField_2.Editable = 'off';
            app.CalculateButton = uibutton(app.DesignPointMKSUnitsPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.InterfacetoXFLR5Label = uilabel(app.UIFigure);
            app.InterfacetoXFLR5Label.Text = 'Interface to XFLR5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfaceToXLFR5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end